classdef HARPredictAppDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UploadButton   matlab.ui.control.Button
        ResultLabel    matlab.ui.control.Label
        PredictButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)


    % Load trained network (persistent so it loads once only)
    persistent net XTest YTest
    if isempty(net)
        % Load trained network
        net = load("trainedHARNet.mat").net;

        % Load test data
        load('rawSensorData_test.mat');
        rawDataTest = table(total_acc_x, total_acc_y, total_acc_z, ...
                            body_gyro_x, body_gyro_y, body_gyro_z);
        numSig_test = size(rawDataTest,1);

        % Also load train data (needed for groupByActivity)
        load('rawSensorData_train.mat');
        rawDataTrain = table(total_acc_x, total_acc_y, total_acc_z, ...
                             body_gyro_x, body_gyro_y, body_gyro_z);
        numSig_train = size(rawDataTrain,1);

        % Group into 6×128×N format
        [~, grouped_test] = groupByActivity(rawDataTrain, numSig_train, ...
                                            rawDataTest, numSig_test);

        % Convert to cell array
        XTest = squeeze(num2cell(grouped_test,[1 2]));

        % Labels
        YTest = testActivity; 
    end

    % Pick a random test sample
    idx = randi(numel(XTest));
    sample = XTest{idx};

    % Predict
    predictedLabel = classify(net, {sample});

    % Show Predicted vs Actual
    actualLabel = string(YTest(idx));
    app.ResultLabel.Text = "Predicted: " + string(predictedLabel) + ...
                           " | Actual: " + actualLabel;
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            % Ask user for file
    [file, path] = uigetfile('.mat','Select your sensor data file');
    if isequal(file,0)
        return; % user canceled
    end
    data = load(fullfile(path,file));

    % Build user data table
    rawDataUser = table(data.total_acc_x, data.total_acc_y, data.total_acc_z, ...
                        data.body_gyro_x, data.body_gyro_y, data.body_gyro_z);

    % Prepare sequences (re-use groupByActivity)
    numSig_user = size(rawDataUser,1);
    [user_group, ~] = groupByActivity(rawDataUser, numSig_user, ...
                                      rawDataUser, numSig_user); % test side unused
    XUser = squeeze(num2cell(user_group, [1 2]));

    % Load trained model
    persistent net
    if isempty(net)
        net = load("trainedHARNet.mat").net;
    end

    % Predict all user sequences
    YPredUser = classify(net, XUser);

    % Convert sequences -> total time
    segmentTime = 2.56; % seconds per sequence
    activities = categories(YPredUser);
    counts = countcats(YPredUser);

    % Build summary text
    msg = "";
    for i = 1:numel(activities)
        totalSec = counts(i) * segmentTime;
        if totalSec > 0
            totalHours = totalSec / 3600;
            msg = msg + sprintf("You %s for %.2f hours today!\n", activities{i}, totalHours);
        end
    end

    % Display results
    app.ResultLabel.Text = msg;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Waiting for Prediction to be made...';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HARPredictAppDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end